classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        StartButton          matlab.ui.control.Button
        DealerTextArea       matlab.ui.control.TextArea
        DealerTextAreaLabel  matlab.ui.control.Label
        PlayerHand           matlab.ui.control.TextArea
        PlayerTextAreaLabel  matlab.ui.control.Label
        BalanceLabel         matlab.ui.control.Label
        BetAmount            matlab.ui.control.NumericEditField
        PlaceBetButton       matlab.ui.control.Button
        StandButton          matlab.ui.control.Button
        HitButton            matlab.ui.control.Button
    end


    properties (Access = private)
        Game % Instance of BlackjackTest class
    end

    methods (Access = private)
        % Function to start or reset the game
        function startGame(app)
            app.Game = BlackjackTest();  % Initialize the Blackjack game
            app.Game.playerHandString = '';  % Clear player hand string
            app.Game.dealerHandString = '';  % Clear dealer hand string
            app.BetAmount.Value = 0;  % Reset bet amount field to 0
            app.updateUI();  % Update the UI to reflect new game state
        end

        % Callback for Place Bet button
        function placeBet(app)
            betAmount = app.BetAmount.Value;  % Get the bet amount from the numeric edit field

            % Check if the bet amount is valid
            if betAmount < 1 || betAmount > app.Game.balance
                uialert(app.UIFigure, 'Invalid bet amount! Please enter a value between 1 and your current balance.', 'Bet Error');
                return;
            end

            % Update game state with the bet amount
            app.Game.betAmount = betAmount;
            app.Game.balance = app.Game.balance - betAmount;  % Deduct the bet from the balance

            % Deal initial cards
            [app.Game.playerHand, app.Game] = app.Game.dealCard('player', 2);  % Deal 2 cards to player
            [app.Game.dealerHand, app.Game] = app.Game.dealCard('dealer', 2);  % Deal 2 cards to dealer

            % Update the UI to reflect the new game state
            app.updateUI();
            app.checkBust();  % Check if the player has busted after dealing
        end

        % Callback for Hit button
        function hit(app)
            % Deal one card to the player
            app.Game = app.Game.dealCard('player', 1);

            % Update the UI after hitting
            app.updateUI();

            % Check if the player has busted
            if app.Game.calculateTotal(app.Game.playerHand) > 21
                app.declareOutcome('Bust! Dealer Wins!');
            end
        end

        % Callback for Stand button
        function stand(app)
            app.Game = app.Game.dealerTurn(app.Game.playerHand, app.Game.dealerHand, app.Game.betAmount);
            app.updateUI();
            playerTotal = app.Game.calculateTotal(app.Game.playerHand);
            dealerTotal = app.Game.calculateTotal(app.Game.dealerHand);
            app.declareOutcome(app.Game.determineWinnerMessage(playerTotal, dealerTotal));
        end

        % Function to update UI elements
        function updateUI(app)
            app.BalanceLabel.Text = ['Balance: $', num2str(app.Game.balance)];
            app.PlayerHand.Value = app.Game.playerHandString;  % Display player's hand
            app.DealerTextArea.Value = app.Game.dealerHandString;  % Display dealer's hand
        end

        % Function to declare outcome and reset game
        function declareOutcome(app, message)
            uialert(app.UIFigure, message, 'Game Over');
            app.startGame(); % Reset game for a new round
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.hit();
        end

        % Button pushed function: PlaceBetButton
        function PlaceBetButtonPushed(app, event)
            app.placeBet();
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            app.stand();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Game = BlackjackTest();  % Initialize the game
            app.Game = app.Game.play(app);  % Start the game and pass the app reference
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HitButton = uibutton(app.Panel, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.Panel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.PlaceBetButton = uibutton(app.Panel, 'push');
            app.PlaceBetButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceBetButtonPushed, true);
            app.PlaceBetButton.Text = 'Place Bet';
            app.BetAmount = uieditfield(app.Panel, 'numeric');
            app.BalanceLabel = uilabel(app.Panel);
            app.BalanceLabel.Text = 'Balance';
            app.PlayerTextAreaLabel = uilabel(app.Panel);
            app.PlayerTextAreaLabel.Text = 'Player';
            app.PlayerHand = uitextarea(app.Panel);
            app.DealerTextAreaLabel = uilabel(app.Panel);
            app.DealerTextAreaLabel.Text = 'Dealer';
            app.DealerTextArea = uitextarea(app.Panel);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end